classdef imghandle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Menu      matlab.ui.container.Menu
        img1      matlab.ui.control.UIAxes
        img2      matlab.ui.control.UIAxes
        img3      matlab.ui.control.UIAxes
        img4      matlab.ui.control.UIAxes
        Button    matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Label     matlab.ui.control.Label
    end

%init function 
    properties (Access = private)
        im;
        gray_img;
        img_adj;
        HIS_ENABLE = 0;%直方图绘制标志位
        point_flag = 0;%画点标志位
        
    end
    
    methods (Access = private)
        %直方图1绘制函数
        function Histogram_draw(app)
            w1=app.gray_img;
            [Y,X]=size(w1); % Y Row , X column
            grayvalue=unique(w1);
            imginfo=[];
            for i=1:length(grayvalue)
                    [ANSy,ANSx]=find(w1==grayvalue(i));
                    imginfo.gray(i)=grayvalue(i);
                    imginfo.position{i}=[ANSy,ANSx];
                    imginfo.count(i)=length(ANSy)/(Y*X);
            end
            stem(app.img2,imginfo.gray,imginfo.count,'Marker','none');%stem(x， y)；绘制以x为横轴、 y为纵轴的脉冲杆图图形
            xlabel(app.img2,'灰度'),ylabel(app.img2,'归一化值'),axis(app.img2,[0 255 0 max(imginfo.count)]);
            title(app.img2,'直方图统计');
        end
        
        
        %直方图2绘制函数
        function Histogram2_draw(app)
            w2=app.img_adj;
            [Y,X]=size(w2); % Y Row , X column
            grayvalue=unique(w2);
            imginfo=[];
            for i=1:length(grayvalue)
                    [ANSy,ANSx]=find(w2==grayvalue(i));
                    imginfo.gray(i)=grayvalue(i);
                    imginfo.position{i}=[ANSy,ANSx];
                    imginfo.count(i)=length(ANSy)/(Y*X);
            end
            stem(app.img4,imginfo.gray,imginfo.count,'Marker','none');%stem(x， y)；绘制以x为横轴、 y为纵轴的脉冲杆图图形
            xlabel(app.img4,'灰度'),ylabel(app.img4,'归一化值'),axis(app.img4,[0 255 0 max(imginfo.count)]);
            title(app.img4,'直方图统计');
        end
        
        %坐标点选取函数
        function [x1 ,x2] = selectDataPoints(app, ax)
            roi = drawpoint(ax);
            x1 = roi.Position(1);
            roi2 = drawpoint(ax);
            x2 = roi2.Position(1);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             xlabel(app.img2,'灰度'),ylabel(app.img2,'归一化值'),axis(app.img2,[0 255 0 1]);
%             title(app.img2,'直方图统计');
%             
%             xlabel(app.img4,'灰度'),ylabel(app.img4,'归一化值'),axis(app.img4,[0 255 0 1]);
%             title(app.img4,'直方图统计');
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            [filename,pathname]=uigetfile({'*.jpg';'*.bmp';'*.tif';'*.*'},"选择图像");%此处的代码是通过访问图像，返回图像的文件名和路径
            if isequal(filename,0)||isequal(pathname,0)%此时判断是否成功选择文件，如果没有则提示错误提示
                errordlg("没有选中文件","错误");%错误提示的信息为
            else
                file_name=strcat(pathname,filename);%将文件名和路径合成，可以访问图片
                app.im=imread(file_name);%读取图片
                %原灰度图
                app.gray_img=rgb2gray(app.im);
                imshow(app.gray_img,'Parent',app.img1);%显示原图在app.img1
                app.HIS_ENABLE = 1;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if(app.HIS_ENABLE == 1)
                Histogram_draw(app);
                app.point_flag = 1;%可取点
            else             
                errordlg("PLEASE CHOOSE A FILE","error");%错误提示的信息为
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if(app.point_flag == 1)
                [x1, x2] = selectDataPoints(app, app.img2);
                
                app.img_adj = imadjust(app.gray_img,[0 1],[x1/255 x2/255]);
                
                imshow(app.img_adj,'Parent',app.img3);
                Histogram2_draw(app);%绘制调整后的直方图
                
                app.point_flag = 0;
            else
                errordlg("PLEASE DRAW THE HIS","error");%错误提示的信息为  
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = '打开图片';
            app.img1 = uiaxes(app.UIFigure);
            title(app.img1, '原灰度图')
            xlabel(app.img1, '')
            ylabel(app.img1, '')
            app.img2 = uiaxes(app.UIFigure);
            title(app.img2, '灰度分布图')
            xlabel(app.img2, '')
            ylabel(app.img2, '')
            app.img3 = uiaxes(app.UIFigure);
            title(app.img3, '调节后灰度图')
            xlabel(app.img3, '')
            ylabel(app.img3, '')
            app.img4 = uiaxes(app.UIFigure);
            title(app.img4, '调节后灰度分布图')
            xlabel(app.img4, '')
            ylabel(app.img4, '')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '绘制灰度分布图';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '调整灰度';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '图像对比度调整';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imghandle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end